classdef MPA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Menu                        matlab.ui.container.Menu
        Menu_2                      matlab.ui.container.Menu
        ReferencePanel              matlab.ui.container.Panel
        DeleteReferenceButton       matlab.ui.control.Button
        IndividualReferenceLabel    matlab.ui.control.Label
        IndividualReferenceTable    matlab.ui.control.Table
        SessionReference            matlab.ui.control.ListBox
        SessionReferenceLabel       matlab.ui.control.Label
        AnalysisPanel               matlab.ui.container.Panel
        DownloadButton              matlab.ui.control.Button
        AnalyzeDataButton           matlab.ui.control.Button
        Lamp                        matlab.ui.control.Lamp
        AvailableData               matlab.ui.control.ListBox
        AvailableDataLabel          matlab.ui.control.Label
        ReportPanel                 matlab.ui.container.Panel
        Switch                      matlab.ui.control.Switch
        CreateReferenceButton       matlab.ui.control.Button
        ExportButton                matlab.ui.control.Button
        CreateReportButton          matlab.ui.control.Button
        AnalyzedData                matlab.ui.control.ListBox
        AnalyzedDataLabel           matlab.ui.control.Label
        ProfilePanel                matlab.ui.container.Panel
        SaveProfileButton           matlab.ui.control.Button
        DeleteProfileButton         matlab.ui.control.Button
        NewProfileButton            matlab.ui.control.Button
        ProfileTable                matlab.ui.control.Table
        SelectProfileDropDown       matlab.ui.control.DropDown
        SelectProfileDropDownLabel  matlab.ui.control.Label
    end


    properties (Access = private)
        S % Settings: Profiles, VarNames, Source, Fields
        P % Profile: for Profile (ID) contains Rootfolder,Datafolder,SourceId, etc.
        Files
        ProfileId
        ProfileLabels
        DirNames
        VarNames
        baseF


    end

    properties (Access = public)
        SystemPath
        Datum
    end

    methods (Access = private)


        function results = able(app,str)

            app.ProfileTable.Enable = str;
            app.AnalyzedData.Enable = str;
            app.AvailableDataLabel.Enable = str;
            app.SessionReference.Enable = str;
            app.IndividualReferenceTable.Enable = str;
        end
        
        function Files = pickDataToAnalyze(app)
            
            NewList=app.AvailableData.Value;
            [~,id]=ismember(NewList,app.Files.Y);
            Files.Y=app.Files.Y(id);
            % if app.P.Source==1
            Files.X=app.Files.X(id);
            
        end
        
        function Files = pickAnalyzedData(app)
            NewList=app.AnalyzedData.Value;
            [~,id]=ismember(NewList,app.Files.Y);
            Files.Y=app.Files.Y(id);
            % if app.P.Source==1
            Files.X=app.Files.X(id);
        end


        function app = updateRefPanel (app)
            RefList={};
            try
                RefList={app.P.Ref.Name};
            catch
            end
            app.SessionReference.Items=RefList;
        end
        
        function saveSettings(app)
            cd(app.baseF)
            S=app.S;
            save Settings.mat S
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)


            loadSettings()
            app.S=S;
            app.SystemPath=pwd;
            app.baseF=baseF;
            app.Datum=datestr(now,'dd.mm.yyyy_HHMM');
            app.S.Profile.T.Name
            app.ProfileLabels=[string(app.SelectProfileDropDown.Items) {app.S.Profile.T.Name}];
            app.SelectProfileDropDown.Items = app.ProfileLabels;

            
            % disable windows
            
            able(app,'off')

            %%get initial states from app and add to profile callback
            %%'none'

        end

        % Value changed function: SelectProfileDropDown
        function SelectProfileDropDownValueChanged(app, event)

            % Lamp red

            app.Lamp.Color='r';

            % update ProfileId
            value = app.SelectProfileDropDown.Value;
            app.ProfileId = find(strcmp(value,app.ProfileLabels),1)-1;

            if app.ProfileId==0 % check for 'none' and set state

                % don't show data

                app.ProfileTable.Data=[];
                app.ProfileTable.ColumnName={};
                app.AnalyzedData.Items={};
                app.AvailableData.Items = {};
                app.SessionReference.Items={};
                app.IndividualReferenceTable.Data=[];
                app.IndividualReferenceTable.ColumnName={};

                % disable windows
                
                able(app,'off')
                
            else

                % able windows
                
                able(app,'on')
                
                % update Profile

                app.P=profileset(app.S,app.ProfileId);
                
                % update profile table

                rm={'Norm','Ref','Source'};
                NewProfileTable=rmfield(app.P,rm);
                NewProfileTable=struct2table(NewProfileTable);
                app.ProfileTable.Data=NewProfileTable;
                app.ProfileTable.ColumnName = NewProfileTable.Properties.VariableNames;
                app.P=addDataBase(app.P)
                
                % update analyzed data

                NewItems=getAnalyzedSessions(app.P.T.Rootfolder);
                app.AnalyzedData.Items=NewItems;

                % update data to analyze

                app.Files=getSessionsToAnalyze(app.P);
                app.AvailableData.Items=app.Files.Y;

                % update individual reference

                if isempty(app.P.Norm)
                    app.IndividualReferenceTable.Data=[];
                    app.IndividualReferenceTable.ColumnName={};
                else
                    NewIndividualReference=app.P.Norm;
                    app.IndividualReferenceTable.Data=NewIndividualReference;
                    app.IndividualReferenceTable.ColumnName = NewIndividualReference.Properties.VariableNames;
                end

                % update Ref

                app = updateRefPanel (app);

            end
            
            % Lamp green
          
            app.Lamp.Color='g';
            
        end

        % Callback function
        function SelectDataButtonPushed(app, event)
            try
                app.DirNames=uigetdir2;
                app.AvailableData.Items=app.DirNames;
            catch
            end

        end

        % Button pushed function: CreateReportButton
        function CreateReportButtonPushed(app, event)
          
        end

        % Button pushed function: AnalyzeDataButton
        function AnalyzeDataButtonPushed(app, event)

            % Lamp green
          
            app.Lamp.Color='r';
            
            % pick sessions
            
            files = pickDataToAnalyze(app);

            % analyze Sessions
            
            app.P=analyzeGames(app.S,app.P,files,app.Datum);
            
            % Lamp red
          
            app.Lamp.Color='g';

        end

        % Button pushed function: CreateReferenceButton
        function CreateReferenceButtonPushed(app, event)
            % Lamp green
          
            app.Lamp.Color='r';
            
            % get ref name
            
            prompt = {'Enter Reference Name'};
            dlgtitle = 'Create Reference';
            dims = [1 35];
            answer = inputdlg(prompt,dlgtitle,dims);
            name=char(answer);
            
            % pick sessions
            
            files=app.AnalyzedData.Value;
           
            % get tables
            
            T=getTablesforRef(files,app.P);
            
            % calc ref
            
            Output=RefCalc(T,app.S.VarNames,name);
            app.P.Ref(end+1)=Output;
            
            % update panel
            
            RefList={app.P.Ref.Name};
            app.SessionReference.Items=RefList;
            
            % save ref
                      
            app.S.Profile(app.ProfileId).Ref=app.P.Ref;
            saveSettings(app)
            
            % Lamp red
          
            app.Lamp.Color='g';
            
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

% % Close request function 
% % to display a question dialog box 
%    selection = questdlg('Close This Figure?',...
%       'Close Request Function',...
%       'Yes','No','Yes'); 
%    switch selection 
%       case 'Yes'
%          delete(gcf)
%       case 'No'
%       return 
%    end
            delete(app)
            
        end

        % Button pushed function: DeleteReferenceButton
        function DeleteReferenceButtonPushed(app, event)

            % delete ref
            
            [~,id]=ismember(app.SessionReference.Value,{app.P.Ref.Name});
            
            selection = questdlg('Delete Reference?',...
                'Delete Reference',...
                'Yes','No','Yes');
            switch selection
                case 'Yes'
                    app.P.Ref(id)=[];
                case 'No'
                    return
            end

            % update ref panel
            
            app = updateRefPanel (app)
            
            % save Settings
            
            app.S.Profile(app.ProfileId).Ref=app.P.Ref;
            saveSettings(app)
            
        end

        % Button pushed function: DeleteProfileButton
        function DeleteProfileButtonPushed(app, event)
            
            % delete profile
            
            [~,id]=ismember(app.SelectProfileDropDown.Value,{app.S.Profile.Name});
            
            selection = questdlg('Delete Profile?',...
                'Delete Profile',...
                'Yes','No','Yes');
            switch selection
                case 'Yes'
                    app.S.Profile(id)=[];
                case 'No'
                    return
            end
            
            % update profile dropdown
            
            app.ProfileId=0;
            SelectProfileDropDownValueChanged(app)
            
            % save Settings
            
            %saveSettings(app)
            
        end

        % Button pushed function: SaveProfileButton
        function SaveProfileButtonPushed(app, event)
            app.S.Profile(app.ProfileId).T=app.ProfileTable;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.Menu_2 = uimenu(app.Menu);
            app.Menu_2.Text = 'Menu';
            app.ProfilePanel = uipanel(app.UIFigure);
            app.SelectProfileDropDownLabel = uilabel(app.ProfilePanel);
            app.SelectProfileDropDownLabel.Text = 'Select Profile';
            app.SelectProfileDropDown = uidropdown(app.ProfilePanel);
            app.SelectProfileDropDown.Items = {'None'};
            app.SelectProfileDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectProfileDropDownValueChanged, true);
            app.SelectProfileDropDown.Value = 'None';
            app.ProfileTable = uitable(app.ProfilePanel);
            app.ProfileTable.ColumnName = {''};
            app.ProfileTable.RowName = {};
            app.ProfileTable.ColumnEditable = true;
            app.NewProfileButton = uibutton(app.ProfilePanel, 'push');
            app.NewProfileButton.Text = 'New Profile';
            app.DeleteProfileButton = uibutton(app.ProfilePanel, 'push');
            app.DeleteProfileButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteProfileButtonPushed, true);
            app.DeleteProfileButton.Text = 'Delete Profile';
            app.SaveProfileButton = uibutton(app.ProfilePanel, 'push');
            app.SaveProfileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveProfileButtonPushed, true);
            app.SaveProfileButton.Text = 'Save Profile';
            app.ReportPanel = uipanel(app.UIFigure);
            app.AnalyzedDataLabel = uilabel(app.ReportPanel);
            app.AnalyzedDataLabel.Text = 'Analyzed Data';
            app.AnalyzedData = uilistbox(app.ReportPanel);
            app.AnalyzedData.Items = {};
            app.AnalyzedData.Multiselect = 'on';
            app.AnalyzedData.Value = {};
            app.CreateReportButton = uibutton(app.ReportPanel, 'push');
            app.CreateReportButton.ButtonPushedFcn = createCallbackFcn(app, @CreateReportButtonPushed, true);
            app.CreateReportButton.Text = 'Create Report';
            app.ExportButton = uibutton(app.ReportPanel, 'push');
            app.ExportButton.Text = 'Export';
            app.CreateReferenceButton = uibutton(app.ReportPanel, 'push');
            app.CreateReferenceButton.ButtonPushedFcn = createCallbackFcn(app, @CreateReferenceButtonPushed, true);
            app.CreateReferenceButton.Text = 'Create Reference';
            app.Switch = uiswitch(app.ReportPanel, 'slider');
            app.Switch.Items = {'Training', 'Game'};
            app.Switch.Value = 'Training';
            app.AnalysisPanel = uipanel(app.UIFigure);
            app.AvailableDataLabel = uilabel(app.AnalysisPanel);
            app.AvailableDataLabel.Text = 'Available Data';
            app.AvailableData = uilistbox(app.AnalysisPanel);
            app.AvailableData.Items = {};
            app.AvailableData.Multiselect = 'on';
            app.AvailableData.Value = {};
            app.Lamp = uilamp(app.AnalysisPanel);
            app.AnalyzeDataButton = uibutton(app.AnalysisPanel, 'push');
            app.AnalyzeDataButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeDataButtonPushed, true);
            app.AnalyzeDataButton.Text = 'Analyze Data';
            app.DownloadButton = uibutton(app.AnalysisPanel, 'push');
            app.DownloadButton.Text = 'Download';
            app.ReferencePanel = uipanel(app.UIFigure);
            app.SessionReferenceLabel = uilabel(app.ReferencePanel);
            app.SessionReferenceLabel.Text = 'Session Reference';
            app.SessionReference = uilistbox(app.ReferencePanel);
            app.SessionReference.Items = {};
            app.SessionReference.Multiselect = 'on';
            app.SessionReference.Value = {};
            app.IndividualReferenceTable = uitable(app.ReferencePanel);
            app.IndividualReferenceTable.ColumnName = {'LfdNr'; 'SpielerNr'; 'Name'; 'Vorname'; 'Alter'; 'Größe'; 'Gewicht'; 'MP4'; 'EC0'};
            app.IndividualReferenceTable.RowName = {};
            app.IndividualReferenceTable.ColumnSortable = true;
            app.IndividualReferenceTable.ColumnEditable = true;
            app.IndividualReferenceLabel = uilabel(app.ReferencePanel);
            app.IndividualReferenceLabel.Text = 'Individual Reference';
            app.DeleteReferenceButton = uibutton(app.ReferencePanel, 'push');
            app.DeleteReferenceButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteReferenceButtonPushed, true);
            app.DeleteReferenceButton.Text = 'Delete Reference';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MPA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end