classdef MPA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MetabolicPowerAppUIFigure   matlab.ui.Figure
        SettingsMenu                matlab.ui.container.Menu
        NewProfileMenu              matlab.ui.container.Menu
        DeleteProfileMenu           matlab.ui.container.Menu
        SaveProfileMenu             matlab.ui.container.Menu
        ClientInfoMenu              matlab.ui.container.Menu
        ProfilePanel                matlab.ui.container.Panel
        SelectProfileDropDownLabel  matlab.ui.control.Label
        SelectProfileDropDown       matlab.ui.control.DropDown
        NewProfileButton            matlab.ui.control.Button
        DeleteProfileButton         matlab.ui.control.Button
        SaveProfileButton           matlab.ui.control.Button
        ProfileTable                matlab.ui.control.Table
        ReportPanel                 matlab.ui.container.Panel
        AnalyzedDataLabel           matlab.ui.control.Label
        AnalyzedData                matlab.ui.control.ListBox
        CreateReportButton          matlab.ui.control.Button
        ExportButton                matlab.ui.control.Button
        CreateReferenceButton       matlab.ui.control.Button
        Switch                      matlab.ui.control.Switch
        ParameterButton             matlab.ui.control.Button
        AnalysisPanel               matlab.ui.container.Panel
        AvailableDataLabel          matlab.ui.control.Label
        AvailableData               matlab.ui.control.ListBox
        AnalyzeDataButton           matlab.ui.control.Button
        RefreshButton               matlab.ui.control.Button
        RemoveAnalyzedButton        matlab.ui.control.Button
        OnlineDataButton            matlab.ui.control.Button
        ReferencePanel              matlab.ui.container.Panel
        SessionReferenceLabel       matlab.ui.control.Label
        SessionReference            matlab.ui.control.ListBox
        DeleteReferenceButton       matlab.ui.control.Button
        IndividualReferencePanel    matlab.ui.container.Panel
        IndividualReferenceTable    matlab.ui.control.Table
        ExportTableButton           matlab.ui.control.Button
        ImportTableButton           matlab.ui.control.Button
        IndividualReferenceLabel    matlab.ui.control.Label
    end


    properties (Access = private)
        S % Settings: Profiles, VarNames, Source, Fields
        P % Profile: for Profile (ID) contains Rootfolder,Datafolder,SourceId, etc.
        Files
        ProfileId
        ProfileLabels
        DirNames
        VarNames
        baseF
        APIapp % API app
        ProfileApp % Create new profile
    end

    properties (Access = public)
        SystemPath
        Datum
    end

    methods (Access = private)
        
        function able(app,str)
            app.ReferencePanel.Enable=str;
            app.AnalysisPanel.Enable=str;
            app.ReportPanel.Enable=str;
            app.IndividualReferencePanel.Enable=str;
            app.DeleteProfileButton.Enable=str;
            app.SaveProfileButton.Enable=str;
            
            if str=="on" && app.P.SourceId==3
                app.OnlineDataButton.Enable=str;
            else
                app.OnlineDataButton.Enable="off";
            end 
        end
        
        function Files = pickDataToAnalyze(app)
            
            NewList=app.AvailableData.Value;
            [~,id]=ismember(NewList,app.Files.Y);
            Files=app.Files;
            Files.Y=app.Files.Y(id);
            if app.P.SourceId==1
                Files.X=app.Files.X(id);
            end   
        end
        
        function Files = pickAnalyzedData(app)
            NewList=app.AnalyzedData.Value;
            [~,id]=ismember(NewList,app.Files.Y);
            Files.Y=app.Files.Y(id);
            if app.P.SourceId==1
            Files.X=app.Files.X(id);
            end
        end
        
        function app = updateRefPanel (app)
            RefList={};
            try
                RefList={app.P.Ref.Name};
            catch
            end
            app.SessionReference.Items=RefList;
        end
        
        function saveSettings(app)
            cd(app.baseF)
            checkSource(app)
            S=app.S;
            save Settings.mat S
                        
        end
        
        function updateIndReference(app)
            NewIndividualReference=app.P.Norm;
            app.IndividualReferenceTable.Data=NewIndividualReference;
            app.IndividualReferenceTable.ColumnName = NewIndividualReference.Properties.VariableNames;
        end
        
        function checkSource(app)
            
            for a=1:numel(app.S.Profile)
                app.S.Profile(a).Datasource=...
                    app.S.Source.Source(app.S.Profile(a).SourceId);
            end
            
        end   
   
    end
    
    methods (Access = public)
        
        function updateAvailableData(app)
            app.Files=getSessionsToAnalyze(app.P);
            app.AvailableData.Items=app.Files.Y;
        end
        
        function newprofilecreated(app)
            startupFcn(app)
            SelectProfileDropDownValueChanged(app)
            %app.SelectProfileDropDown.Value=1;
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            loadSettings()
            app.S=S;
            app.SystemPath=pwd;
            app.baseF=baseF;
            app.Datum=datestr(now,'dd.mm.yyyy_HHMM');

            app.ProfileLabels=[string(app.SelectProfileDropDown.Items(1)) {app.S.Profile.Name}];
            app.SelectProfileDropDown.Items = app.ProfileLabels;
            
            % disable windows
            
            able(app,'off')

        end

        % Value changed function: SelectProfileDropDown
        function SelectProfileDropDownValueChanged(app, event)


            % update ProfileId
            value = app.SelectProfileDropDown.Value;
            app.ProfileId = find(strcmp(value,app.ProfileLabels),1)-1;

            if app.ProfileId==0 % check for 'none' and set state

                % blank panels

                app.ProfileTable.Data=[];
                app.ProfileTable.ColumnName={};
                app.AnalyzedData.Items={};
                app.AvailableData.Items = {};
                app.SessionReference.Items={};
                app.IndividualReferenceTable.Data=[];
                app.IndividualReferenceTable.ColumnName={};

                % disable panels
                
                able(app,'off')

            else
                
                % update Profile

                app.P=profileset(app.S,app.ProfileId);
                app.P=addDataBase(app.P);
                                
                 % able panels
                
                able(app,'on')
                
                % update profile table

                rm={'Norm','Ref','Source'};
                NewProfileTable=rmfield(app.P,rm);
                NewProfileTable=struct2table(NewProfileTable);
                app.ProfileTable.Data=NewProfileTable;
                app.ProfileTable.ColumnName = NewProfileTable.Properties.VariableNames;
                                
                % update analyzed data

                NewItems=getAnalyzedSessions(app.P.Rootfolder);
                app.AnalyzedData.Items=NewItems;

                % update data to analyze

                updateAvailableData(app)

                % update individual reference

                updateIndReference(app)

                % update Ref

                updateRefPanel (app);

            end
            
            
        end

        % Callback function
        function SelectDataButtonPushed(app, event)
            
        end

        % Button pushed function: CreateReportButton
        function CreateReportButtonPushed(app, event)
         
        end

        % Button pushed function: AnalyzeDataButton
        function AnalyzeDataButtonPushed(app, event)
            
            % pick sessions
            
            files = pickDataToAnalyze(app);
            
            % progress panel
            
%             try
                fig = app.MetabolicPowerAppUIFigure;
                count='0';
                numSession=numel(files.Y);
                to=num2str(numSession);
                msg=['Analyzing ' to ' Session(s) ...'];
                d = uiprogressdlg(fig,'Title','Analyzing Data',...
                    'Message',[msg newline count '/' to]);
                pause(2)

                % analyze Sessions
                
                for a=1:numel(files.Y)
                    file=files;
                    file.Y=file.Y(a);
                    if app.P.SourceId==1
                        file.X=files.X(a);
                    end
                    app.P=analyzeGames(app.S,app.P,file,app.Datum);
                    d.Value=a/numSession;
                    d.Message=[msg newline num2str(a) '/' to];
                    pause(1)
                end
                
                % update panels
                
                NewItems=getAnalyzedSessions(app.P.Rootfolder);
                app.AnalyzedData.Items=NewItems;
                updateIndReference(app)
                
                % close & catch
                close (d)
                msg=['Analyzed ' num2str(a) '/' to ' session(s)'];
                title='Analysis successful';
                uiconfirm(fig,msg,title,...
                    'Options',{'OK'}, ...
                    'Icon', 'success');
  
%             catch ME
%                 close(d)
%                 msg=ME.message;
%                 uialert(fig,msg,'Analysis Error');
%             end

        end

        % Button pushed function: CreateReferenceButton
        function CreateReferenceButtonPushed(app, event)

            % get ref name
            
            prompt = {'Enter Reference Name'};
            dlgtitle = 'Create Reference';
            dims = [1 35];
            answer = inputdlg(prompt,dlgtitle,dims);
            name=char(answer);
            
            % progress bar
            
            fig = app.MetabolicPowerAppUIFigure;
            msg=['Creating Reference: ' answer];
            d = uiprogressdlg(fig,'Title','Reference',...
                'Message',msg);
            pause(2)
            
            % pick sessions
            
            files=app.AnalyzedData.Value;
           
            % get tables
            
            T=getTablesforRef(files,app.P);
            
            % calc ref
            
            Output=RefCalc(T,app.S.VarNames,name);
            [app.S, app.P]=appendRef(app.S,Output,app.ProfileId);
            
            % update panel
            
            RefList={app.P.Ref.Name};
            app.SessionReference.Items=RefList;
            
            % save ref
                      
            saveSettings(app)
            SelectProfileDropDownValueChanged(app)
            
            % confirm dlg
            close(d)
            title='Reference';
            msg=['Created Reference ' answer];
            d = uiconfirm(fig,msg,title,...
                    'Options',{'OK'}, ...
                    'Icon', 'success');
            
        end

        % Close request function: MetabolicPowerAppUIFigure
        function MetabolicPowerAppUIFigureCloseRequest(app, event)

% % Close request function 
% % to display a question dialog box 
%    selection = questdlg('Close This Figure?',...
%       'Close Request Function',...
%       'Yes','No','Yes'); 
%    switch selection 
%       case 'Yes'
%          delete(gcf)
%       case 'No'
%       return 
%    end
            delete(app)
            
        end

        % Button pushed function: DeleteReferenceButton
        function DeleteReferenceButtonPushed(app, event)

            % delete ref
            
            [~,id]=ismember(app.SessionReference.Value,{app.P.Ref.Name});
            
            % confirm dlg
            
            fig=app.MetabolicPowerAppUIFigure;
            msg='Delete Reference(s)?';
            title='Delete Reference';
            selection = uiconfirm(fig,msg,title);

            switch selection
                case 'OK'
                    app.P.Ref(id)=[];
                case 'Cancel'
                    return
            end

            % update ref panel
            
            app = updateRefPanel (app);
            
            % save Settings
            
            app.S.Profile(app.ProfileId).Ref=app.P.Ref;
            saveSettings(app)
            
        end

        % Callback function: DeleteProfileButton, DeleteProfileMenu
        function DeleteProfileButtonPushed(app, event)
            
            % delete profile
            
            [~,id]=ismember(string(app.SelectProfileDropDown.Value),string({app.S.Profile.Name}));
            
            % confirm dlg
            
            fig=app.MetabolicPowerAppUIFigure;
            msg='Delete Profile?';
            title='Delete Profile';
            selection = uiconfirm(fig,msg,title);

            switch selection
                case 'OK'
                    app.S.Profile(id)=[];
                case 'Cancel'
                    return
            end
            
            % save Settings
            
            saveSettings(app)
            
            startupFcn(app)
            
            SelectProfileDropDownValueChanged(app, event)         
                                             
        end

        % Callback function: SaveProfileButton, SaveProfileMenu
        function SaveProfileButtonPushed(app, event)

            fig=app.MetabolicPowerAppUIFigure;
            msg='Save Profile?';
            title='Save Profile';
            selection = uiconfirm(fig,msg,title);

            switch selection
                case 'OK'
                    T=app.ProfileTable.Data;
                    SaveProfile=table2struct(T);
                    SaveProfile=rmfield(SaveProfile,'DB');
                    NewNorm=app.IndividualReferenceTable.Data;
                    SaveProfile.Norm=NewNorm;
                    SaveProfile.Ref=app.P.Ref;
               
                    app.S.Profile(app.ProfileId)=SaveProfile;
                    app.S.Profile(app.ProfileId)
                    saveSettings(app)
                case 'Cancel'
                    return
            end

        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)

            % pick sessions
            
            files=app.AnalyzedData.Value;
            
            % progress dlg
            
            fig=app.MetabolicPowerAppUIFigure;
            numSession=num2str(numel(files));
            title='Export';
            msg=['Exporting ' numSession ' Session(s)'];
            d = uiprogressdlg(fig, 'Title',title,'Message', msg);
            
            % export tables
                     
            exportTables(files, app.P)
            
            % confirm dlg 

            close(d)
            msg=['Exported ' numSession ' session(s)'];
            uiconfirm(fig,msg,title,...
                    'Options',{'OK'}, ...
                    'Icon', 'success');

        end

        % Button pushed function: RemoveAnalyzedButton
        function RemoveAnalyzedButtonPushed(app, event)
            
            % get items from panels
            
            An=string(app.AnalyzedData.Items);
            Av=string(app.AvailableData.Items);
            
            % transform to match yyyymmdd_HHMMSS
            
            if app.P.SourceId==1

            An=extractBefore(An,".mat");
            id=strfind(Av,"_");
            id=cell2mat(id);
            Av=extractBefore(Av,id(:,2)); 
            
            end

            % delete analyzed items
            
            id=ismember(Av,An);
            app.AvailableData.Items(id)=[];
                   
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            app.Files=getSessionsToAnalyze(app.P);
            app.AvailableData.Items=app.Files.Y;
        end

        % Button pushed function: OnlineDataButton
        function OnlineDataButtonPushed(app, event)
            
            % import
            
            import matlab.net.*
            import matlab.net.http.*
            
            % Authorization
            
            cd(app.baseF)
          
            access_token=authorizePolar;
            
            [AllSessions, M]=getSessions(app.P.api,access_token);
            
            % start API app
            
            app.APIapp = APIapp(app, AllSessions, M, app.P);
            
        end

        % Callback function: NewProfileButton, NewProfileMenu
        function NewProfileButtonPushed(app, event)
            app.ProfileApp = NewProfile(app);
        end

        % Button pushed function: ExportTableButton
        function ExportTableButtonPushed(app, event)
            cd(app.P.Rootfolder)
            ExportTable=app.IndividualReferenceTable.Data;
            writetable(ExportTable,'Norm_Export.xlsx')
            
            % confirm dlg
            
            fig=app.MetabolicPowerAppUIFigure;
            title='Export';
            msg='Exported Individual Reference';
            uiconfirm(fig,msg,title,...
                    'Options',{'OK'}, ...
                    'Icon', 'success');
            
        end

        % Button pushed function: ImportTableButton
        function ImportTableButtonPushed(app, event)
            [file,path] = uigetfile('*.xls*');
            if isequal(file,0)
                return
            else
                T=readtable(fullfile(path,file));
                app.IndividualReferenceTable.Data=T;
                
                % confirm dlg
                
                fig=app.MetabolicPowerAppUIFigure;
                title='Import';
                msg='Imported Individual Reference';
                uiconfirm(fig,msg,title,...
                    'Options',{'OK'}, ...
                    'Icon', 'success');
            end
        end

        % Menu selected function: ClientInfoMenu
        function ClientInfoMenuSelected(app, event)
            load("client.mat")
            d=inputdlg({'Client ID','Client Secret'},...
                'Modify client information', [1 50; 1 50],...
                {client.id client.secret});
            if ~isempty(d)
                client.id=d{1};
                client.secret=d{2};
                save client.mat client
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MetabolicPowerAppUIFigure = uifigure('Visible', 'off');
            app.MetabolicPowerAppUIFigure.Name = 'Metabolic Power App';
            app.MetabolicPowerAppUIFigure.CloseRequestFcn = createCallbackFcn(app, @MetabolicPowerAppUIFigureCloseRequest, true);
            app.SettingsMenu = uimenu(app.MetabolicPowerAppUIFigure);
            app.SettingsMenu.Text = 'Settings';
            app.NewProfileMenu = uimenu(app.SettingsMenu);
            app.NewProfileMenu.MenuSelectedFcn = createCallbackFcn(app, @NewProfileButtonPushed, true);
            app.NewProfileMenu.Text = 'New Profile';
            app.DeleteProfileMenu = uimenu(app.SettingsMenu);
            app.DeleteProfileMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteProfileButtonPushed, true);
            app.DeleteProfileMenu.Text = 'Delete Profile';
            app.SaveProfileMenu = uimenu(app.SettingsMenu);
            app.SaveProfileMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveProfileButtonPushed, true);
            app.SaveProfileMenu.Text = 'Save Profile';
            app.ClientInfoMenu = uimenu(app.SettingsMenu);
            app.ClientInfoMenu.MenuSelectedFcn = createCallbackFcn(app, @ClientInfoMenuSelected, true);
            app.ClientInfoMenu.Text = 'Client Info';
            app.ProfilePanel = uipanel(app.MetabolicPowerAppUIFigure);
            app.SelectProfileDropDownLabel = uilabel(app.ProfilePanel);
            app.SelectProfileDropDownLabel.Text = 'Select Profile';
            app.SelectProfileDropDown = uidropdown(app.ProfilePanel);
            app.SelectProfileDropDown.Items = {'None'};
            app.SelectProfileDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectProfileDropDownValueChanged, true);
            app.SelectProfileDropDown.Value = 'None';
            app.NewProfileButton = uibutton(app.ProfilePanel, 'push');
            app.NewProfileButton.ButtonPushedFcn = createCallbackFcn(app, @NewProfileButtonPushed, true);
            app.NewProfileButton.Text = 'New Profile';
            app.DeleteProfileButton = uibutton(app.ProfilePanel, 'push');
            app.DeleteProfileButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteProfileButtonPushed, true);
            app.DeleteProfileButton.Text = 'Delete Profile';
            app.SaveProfileButton = uibutton(app.ProfilePanel, 'push');
            app.SaveProfileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveProfileButtonPushed, true);
            app.SaveProfileButton.Text = 'Save Profile';
            app.ProfileTable = uitable(app.ProfilePanel);
            app.ProfileTable.ColumnName = '';
            app.ProfileTable.RowName = {};
            app.ProfileTable.ColumnSortable = true;
            app.ProfileTable.ColumnEditable = true;
            app.ReportPanel = uipanel(app.MetabolicPowerAppUIFigure);
            app.AnalyzedDataLabel = uilabel(app.ReportPanel);
            app.AnalyzedDataLabel.Text = 'Analyzed Data';
            app.AnalyzedData = uilistbox(app.ReportPanel);
            app.AnalyzedData.Items = {};
            app.AnalyzedData.Multiselect = 'on';
            app.AnalyzedData.Value = {};
            app.CreateReportButton = uibutton(app.ReportPanel, 'push');
            app.CreateReportButton.ButtonPushedFcn = createCallbackFcn(app, @CreateReportButtonPushed, true);
            app.CreateReportButton.Enable = 'off';
            app.CreateReportButton.Text = 'Create Report';
            app.ExportButton = uibutton(app.ReportPanel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.CreateReferenceButton = uibutton(app.ReportPanel, 'push');
            app.CreateReferenceButton.ButtonPushedFcn = createCallbackFcn(app, @CreateReferenceButtonPushed, true);
            app.CreateReferenceButton.Text = 'Create Reference';
            app.Switch = uiswitch(app.ReportPanel, 'slider');
            app.Switch.Items = {'Training', 'Game'};
            app.Switch.Value = 'Training';
            app.ParameterButton = uibutton(app.ReportPanel, 'push');
            app.ParameterButton.Enable = 'off';
            app.ParameterButton.Text = 'Parameter';
            app.AnalysisPanel = uipanel(app.MetabolicPowerAppUIFigure);
            app.AvailableDataLabel = uilabel(app.AnalysisPanel);
            app.AvailableDataLabel.Text = 'Available Data';
            app.AvailableData = uilistbox(app.AnalysisPanel);
            app.AvailableData.Items = {};
            app.AvailableData.Multiselect = 'on';
            app.AvailableData.Value = {};
            app.AnalyzeDataButton = uibutton(app.AnalysisPanel, 'push');
            app.AnalyzeDataButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeDataButtonPushed, true);
            app.AnalyzeDataButton.Text = 'Analyze Data';
            app.RefreshButton = uibutton(app.AnalysisPanel, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.RemoveAnalyzedButton = uibutton(app.AnalysisPanel, 'push');
            app.RemoveAnalyzedButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveAnalyzedButtonPushed, true);
            app.RemoveAnalyzedButton.Text = 'Remove Analyzed';
            app.OnlineDataButton = uibutton(app.AnalysisPanel, 'push');
            app.OnlineDataButton.ButtonPushedFcn = createCallbackFcn(app, @OnlineDataButtonPushed, true);
            app.OnlineDataButton.Text = 'Online Data';
            app.ReferencePanel = uipanel(app.MetabolicPowerAppUIFigure);
            app.SessionReferenceLabel = uilabel(app.ReferencePanel);
            app.SessionReferenceLabel.Text = 'Session Reference';
            app.SessionReference = uilistbox(app.ReferencePanel);
            app.SessionReference.Items = {};
            app.SessionReference.Multiselect = 'on';
            app.SessionReference.Value = {};
            app.DeleteReferenceButton = uibutton(app.ReferencePanel, 'push');
            app.DeleteReferenceButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteReferenceButtonPushed, true);
            app.DeleteReferenceButton.Text = 'Delete Reference';
            app.IndividualReferencePanel = uipanel(app.MetabolicPowerAppUIFigure);
            app.IndividualReferenceTable = uitable(app.IndividualReferencePanel);
            app.IndividualReferenceTable.ColumnName = '';
            app.IndividualReferenceTable.RowName = {};
            app.IndividualReferenceTable.ColumnSortable = true;
            app.IndividualReferenceTable.ColumnEditable = true;
            app.ExportTableButton = uibutton(app.IndividualReferencePanel, 'push');
            app.ExportTableButton.ButtonPushedFcn = createCallbackFcn(app, @ExportTableButtonPushed, true);
            app.ExportTableButton.Text = 'Export Table';
            app.ImportTableButton = uibutton(app.IndividualReferencePanel, 'push');
            app.ImportTableButton.ButtonPushedFcn = createCallbackFcn(app, @ImportTableButtonPushed, true);
            app.ImportTableButton.Text = 'Import Table';
            app.IndividualReferenceLabel = uilabel(app.IndividualReferencePanel);
            app.IndividualReferenceLabel.Text = 'Individual Reference';
            % Show the figure after all components are created
            app.MetabolicPowerAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MPA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MetabolicPowerAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MetabolicPowerAppUIFigure)
        end
    end
end