classdef NewProfile < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NewProfileUIFigure           matlab.ui.Figure
        CreatenewprofilePanel        matlab.ui.container.Panel
        DatasourceDropDownLabel      matlab.ui.control.Label
        DatasourceDropDown           matlab.ui.control.DropDown
        GeneralfolderEditFieldLabel  matlab.ui.control.Label
        GeneralfolderEditField       matlab.ui.control.EditField
        BrowseButtonRoot             matlab.ui.control.Button
        DatafolderEditFieldLabel     matlab.ui.control.Label
        DatafolderEditField          matlab.ui.control.EditField
        BrowseButtonData             matlab.ui.control.Button
        ProfilenameEditFieldLabel    matlab.ui.control.Label
        ProfilenameEditField         matlab.ui.control.EditField
        SaveButton                   matlab.ui.control.Button
        clientidEditFieldLabel       matlab.ui.control.Label
        clientidEditField            matlab.ui.control.EditField
        clientsecretEditFieldLabel   matlab.ui.control.Label
        clientsecretEditField        matlab.ui.control.EditField
    end

    
    properties (Access = private)
        baseF
        S
        MPA % Calling app
    end
    
    methods (Access = private)
        
        function vis(app,str)
            app.clientidEditField.Visible=str;
            app.clientidEditFieldLabel.Visible=str;
            app.clientsecretEditField.Visible=str;
            app.clientsecretEditFieldLabel.Visible=str;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MPA)
            loadSettings()
            app.MPA=MPA;
            app.S=S;
            app.baseF=baseF;
            app.clientidEditField.Visible=true;
            vis(app,false)
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            
            cd(app.baseF)
           
            % check if fields are empty
            
            if isempty(app.ProfilenameEditField.Value) || isempty(app.GeneralfolderEditField.Value) || isempty(app.DatafolderEditField.Value)
                warndlg('please fill the white space','missing information');
                return
                
            end
              
            % create Profile in Settings
            
            ProfileId=numel(app.S.Profile)+1;
            app.S.Profile(ProfileId).Name=string(app.ProfilenameEditField.Value);
            app.S.Profile(ProfileId).Rootfolder=string(app.GeneralfolderEditField.Value);
            app.S.Profile(ProfileId).Datafolder=string(app.DatafolderEditField.Value);
            SourceId=app.DatasourceDropDown.Value;
            app.S.Profile(ProfileId).SourceId=SourceId;
            app.S.Profile(ProfileId).Datasource=string(app.DatasourceDropDown.Items{SourceId});
            app.S.Profile(ProfileId).Norm=app.S.Norm;
            app.S.Profile(ProfileId).api=0;
            
            % check if client data isempty and save
            
            emptyclient=(isempty(app.clientidEditField.Value) || isempty(app.clientsecretEditField.Value));
            
            if app.clientidEditField.Visible && emptyclient
                % add link to desciption
                dlg=questdlg('Enter client information',...
                    'Missing information',...
                    "I don't care", "Ok I'll do it","Ok I'll do it");
                switch dlg
                    case "I don't care"
                       %
                    case "Ok I'll do it"
                        return
                end
            elseif app.clientidEditField.Visible && ~emptyclient
                client.id=app.clientidEditField.Value;
                client.secret=app.clientsecretEditField.Value;
                save client.mat client
            end   
            
            % save Settings
            
            S=app.S;
            save Settings.mat S
            
            newprofilecreated(app.MPA)
            
            delete(app)


        end

        % Button pushed function: BrowseButtonRoot
        function BrowseButtonRootPushed(app, event)
            str=uigetdir(pwd,'Select general folder');
            app.GeneralfolderEditField.Value=str;
        end

        % Button pushed function: BrowseButtonData
        function BrowseButtonDataPushed(app, event)
            str=uigetdir(pwd,'Select data folder');
            app.DatafolderEditField.Value=str;
        end

        % Value changed function: DatasourceDropDown
        function DatasourceDropDownValueChanged(app, event)
            value = app.DatasourceDropDown.Value;
            cd(app.baseF)
           
            if value==3 && ~exist('client.mat','file')
                vis(app,true)
            else
                vis(app,false)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NewProfileUIFigure = uifigure('Visible', 'off');
            app.NewProfileUIFigure.Name = 'New Profile';
            app.CreatenewprofilePanel = uipanel(app.NewProfileUIFigure);
            app.CreatenewprofilePanel.Title = 'Create new profile';
            app.DatasourceDropDownLabel = uilabel(app.CreatenewprofilePanel);
            app.DatasourceDropDownLabel.Text = 'Data source';
            app.DatasourceDropDown = uidropdown(app.CreatenewprofilePanel);
            app.DatasourceDropDown.Items = {'Polar CSV', 'Polar API', 'Kinexon', ''};
            app.DatasourceDropDown.ItemsData = [1 3 4];
            app.DatasourceDropDown.ValueChangedFcn = createCallbackFcn(app, @DatasourceDropDownValueChanged, true);
            app.DatasourceDropDown.Value = 1;
            app.GeneralfolderEditFieldLabel = uilabel(app.CreatenewprofilePanel);
            app.GeneralfolderEditFieldLabel.Text = 'General folder';
            app.GeneralfolderEditField = uieditfield(app.CreatenewprofilePanel, 'text');
            app.BrowseButtonRoot = uibutton(app.CreatenewprofilePanel, 'push');
            app.BrowseButtonRoot.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonRootPushed, true);
            app.BrowseButtonRoot.Text = 'Browse';
            app.DatafolderEditFieldLabel = uilabel(app.CreatenewprofilePanel);
            app.DatafolderEditFieldLabel.Text = 'Data folder';
            app.DatafolderEditField = uieditfield(app.CreatenewprofilePanel, 'text');
            app.BrowseButtonData = uibutton(app.CreatenewprofilePanel, 'push');
            app.BrowseButtonData.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonDataPushed, true);
            app.BrowseButtonData.Text = 'Browse';
            app.ProfilenameEditFieldLabel = uilabel(app.CreatenewprofilePanel);
            app.ProfilenameEditFieldLabel.Text = 'Profile name';
            app.ProfilenameEditField = uieditfield(app.CreatenewprofilePanel, 'text');
            app.SaveButton = uibutton(app.CreatenewprofilePanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.clientidEditFieldLabel = uilabel(app.CreatenewprofilePanel);
            app.clientidEditFieldLabel.Visible = 'off';
            app.clientidEditFieldLabel.Text = 'client id';
            app.clientidEditField = uieditfield(app.CreatenewprofilePanel, 'text');
            app.clientidEditField.Visible = 'off';
            app.clientsecretEditFieldLabel = uilabel(app.CreatenewprofilePanel);
            app.clientsecretEditFieldLabel.Visible = 'off';
            app.clientsecretEditFieldLabel.Text = 'client secret';
            app.clientsecretEditField = uieditfield(app.CreatenewprofilePanel, 'text');
            app.clientsecretEditField.Visible = 'off';
            % Show the figure after all components are created
            app.NewProfileUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NewProfile(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NewProfileUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NewProfileUIFigure)
        end
    end
end